classdef TaskNine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageProcessing                matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ImageViewerTab                 matlab.ui.container.Tab
        BrowseButton                   matlab.ui.control.Button
        PathEditField                  matlab.ui.control.EditField
        WidthLabel                     matlab.ui.control.Label
        HeightLabel                    matlab.ui.control.Label
        SizeinbitsLabel                matlab.ui.control.Label
        BitDepthLabel                  matlab.ui.control.Label
        ImageColorLabel                matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.EditField
        HeightEditField                matlab.ui.control.EditField
        SizeInBitsEditField            matlab.ui.control.EditField
        BitDepthEditField              matlab.ui.control.EditField
        ImageColorEditField            matlab.ui.control.EditField
        ModalityLabel                  matlab.ui.control.Label
        PatientNameLabel               matlab.ui.control.Label
        PatientAgeLabel                matlab.ui.control.Label
        BodyPartExaminedLabel          matlab.ui.control.Label
        ModalityEditField              matlab.ui.control.EditField
        PatientNameEditField           matlab.ui.control.EditField
        PatientAgeEditField            matlab.ui.control.EditField
        BodyPartExaminedEditField      matlab.ui.control.EditField
        ImageDisplay                   matlab.ui.control.UIAxes
        InterpolationTab               matlab.ui.container.Tab
        InterpolateButton              matlab.ui.control.Button
        ZoomingFactorLabel             matlab.ui.control.Label
        ZoomingFactorEditField         matlab.ui.control.NumericEditField
        InterpolationTypeDropDown      matlab.ui.control.DropDown
        InterpolationPanel             matlab.ui.container.Panel
        InterpolatedImage              matlab.ui.control.UIAxes
        OriginalPanel                  matlab.ui.container.Panel
        OriginalImage                  matlab.ui.control.UIAxes
        OriginalImageinGrayscaleLabel  matlab.ui.control.Label
        InterpolatedImageLabel         matlab.ui.control.Label
        OriginalWidthLabel             matlab.ui.control.Label
        OriginalHeightLabel            matlab.ui.control.Label
        NewWidthLabel                  matlab.ui.control.Label
        NewHeightLabel                 matlab.ui.control.Label
        OriginalWidthEditField         matlab.ui.control.EditField
        OriginalHeightEditField        matlab.ui.control.EditField
        NewWidthEditField              matlab.ui.control.EditField
        NewHeightEditField             matlab.ui.control.EditField
        info                           matlab.ui.control.Label
        AffineTransformationTab        matlab.ui.container.Tab
        ConstructImageButton           matlab.ui.control.Button
        AngleSlider                    matlab.ui.control.Slider
        AngleSliderLabel               matlab.ui.control.Label
        InterpolationTypeOfRotatedImageDropDown  matlab.ui.control.DropDown
        InterpolationTypeLabel         matlab.ui.control.Label
        ShearingAngleLabel             matlab.ui.control.Label
        Shear45Button                  matlab.ui.control.Button
        ShearNegative45Button          matlab.ui.control.Button
        AngleLabel                     matlab.ui.control.Label
        DirectionLabel                 matlab.ui.control.Label
        AngleEditField                 matlab.ui.control.EditField
        DirectionEditField             matlab.ui.control.EditField
        RotateWidthLabel               matlab.ui.control.Label
        RotateHeightLabel              matlab.ui.control.Label
        RotateWidthEditField           matlab.ui.control.EditField
        RotateHeightEditField          matlab.ui.control.EditField
        TImage                         matlab.ui.control.UIAxes
        HistogramTab                   matlab.ui.container.Tab
        HistogramBrowseButton          matlab.ui.control.Button
        EqualizedImageLabel            matlab.ui.control.Label
        OriginalImageLabel             matlab.ui.control.Label
        UnequalizedDisplay             matlab.ui.control.UIAxes
        EqualizedDisplay               matlab.ui.control.UIAxes
        UnequalizedHistogram           matlab.ui.control.UIAxes
        EqualizedHistogram             matlab.ui.control.UIAxes
        SpatialFilteringTab            matlab.ui.container.Tab
        SpatialFilterBrowseButton      matlab.ui.control.Button
        BlurredNoisyImageLabel         matlab.ui.control.Label
        KernelsizeLabel                matlab.ui.control.Label
        FactorKLabel                   matlab.ui.control.Label
        KernelSizeSpinner              matlab.ui.control.Spinner
        EnhanceButton                  matlab.ui.control.Button
        RemoveNoiseButton              matlab.ui.control.Button
        EnhancedImageLabel             matlab.ui.control.Label
        FactorKEditField               matlab.ui.control.NumericEditField
        UnfilteredImageDisplay         matlab.ui.control.UIAxes
        Blurred_NoisyDisplay           matlab.ui.control.UIAxes
        EnhancedImageDisplay           matlab.ui.control.UIAxes
        FourierITab                    matlab.ui.container.Tab
        FourierBrowseButton            matlab.ui.control.Button
        LogDropDown                    matlab.ui.control.DropDown
        MagnitudeLabel                 matlab.ui.control.Label
        PhaseLabel                     matlab.ui.control.Label
        OriginalImageLabel_2           matlab.ui.control.Label
        OriginalImageDisplay           matlab.ui.control.UIAxes
        MagnitudeDisplay               matlab.ui.control.UIAxes
        PhaseDisplay                   matlab.ui.control.UIAxes
        FourierIITab                   matlab.ui.container.Tab
        FourierIIBrowseButton          matlab.ui.control.Button
        FourierKernelsizeLabel         matlab.ui.control.Label
        FourierKernelSizeSpinner       matlab.ui.control.Spinner
        FilterButton                   matlab.ui.control.Button
        FilteredImageLabel             matlab.ui.control.Label
        DifferenceLabel                matlab.ui.control.Label
        ImageDisplaay                  matlab.ui.control.UIAxes
        FourierFilterDisplay           matlab.ui.control.UIAxes
        DifferenceDisplay              matlab.ui.control.UIAxes
        RemovePatternTab               matlab.ui.container.Tab
        RemovePatternButton            matlab.ui.control.Button
        PatternedImageLabel            matlab.ui.control.Label
        MaskingLabel                   matlab.ui.control.Label
        PatternRemovedLabel            matlab.ui.control.Label
        Mask                           matlab.ui.control.UIAxes
        ImageWithoutPattern            matlab.ui.control.UIAxes
        ImageWithPattern               matlab.ui.control.UIAxes
        NoiseTab                       matlab.ui.container.Tab
        CreateImageButton              matlab.ui.control.Button
        NoiseTypeDropDown              matlab.ui.control.DropDown
        NoiseTypeLabel                 matlab.ui.control.Label
        ConfirmRegionofInterestButton  matlab.ui.control.Button
        MeanLabel                      matlab.ui.control.Label
        MeanEditField                  matlab.ui.control.EditField
        VarianceLabel                  matlab.ui.control.Label
        VarianceEditField              matlab.ui.control.EditField
        CreatedImage                   matlab.ui.control.UIAxes
        ROIHistogram                   matlab.ui.control.UIAxes
        BackProjectionTab              matlab.ui.container.Tab
        StartButton                    matlab.ui.control.Button
        ScheppLoganPhantomLabel        matlab.ui.control.Label
        SinogramLabel                  matlab.ui.control.Label
        LaminogramLabel                matlab.ui.control.Label
        LaminogramDropDown             matlab.ui.control.DropDown
        ManualButton                   matlab.ui.control.Button
        BuiltinButton                  matlab.ui.control.Button
        ScheppLoganPhantom             matlab.ui.control.UIAxes
        Sinogram                       matlab.ui.control.UIAxes
        Laminogram                     matlab.ui.control.UIAxes
        ImageMorphologyTab             matlab.ui.container.Tab
        DisplayButton                  matlab.ui.control.Button
        FingerprintImageLabel          matlab.ui.control.Label
        MorphologicalProcessingLabel   matlab.ui.control.Label
        DilationButton                 matlab.ui.control.Button
        ErosionButton                  matlab.ui.control.Button
        ClosingButton                  matlab.ui.control.Button
        OpeningButton                  matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        RotateWidthLabel_2             matlab.ui.control.Label
        RotateHeightLabel_2            matlab.ui.control.Label
        FingerprintImage               matlab.ui.control.UIAxes
        MorphologicalImage             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
 %Open dialog box for browsing
 global Filename;
 global Pathname;
 global indx;
            Filename=0;
            [Filename, Pathname,indx] = uigetfile({'*.jpg';'*.jpeg';'*.bmp';'*.dcm';'*.tif';'*.png'}, 'All Files');

            if Filename==0
                return;
            end
            %if extensions jpg or bmp
             if (indx==1)||(indx==2)||(indx==3)||(indx==5)||(indx==6)
                fullname=[Pathname,Filename];
                app.PathEditField.Value=fullname;
                app.ModalityLabel.Visible='off';
                app.ModalityEditField.Visible='off';
                app.PatientNameLabel.Visible='off';
                app.PatientNameEditField.Visible='off';
                app.PatientAgeLabel.Visible='off';
                app.PatientAgeEditField.Visible='off';
                app.BodyPartExaminedLabel.Visible='off';
                app.BodyPartExaminedEditField.Visible='off';
                
                %try & catch error due to corrupted image
                %Display image with its info
                try
                    ImageFile=imread(fullname);
                    imshow(ImageFile,'Parent' ,app.ImageDisplay);
                    [row,col,channels]=size(ImageFile);
                    ImageData=imfinfo(fullname);
                    app.WidthEditField.Value=num2str(col);
                    app.HeightEditField.Value=num2str(row);
                    app.BitDepthEditField.Value=num2str(ImageData.BitDepth);
                    SizeinBits=col*row*ImageData.BitDepth;
%                   
                    
                    
                    app.SizeInBitsEditField.Value=num2str(SizeinBits);
                    if (ImageData.BitDepth==1 && channels==1 )
                        app.ImageColorEditField.Value='Binary';
                    elseif (ImageData.BitDepth==8 && channels==1)
                        app.ImageColorEditField.Value='Grayscale';
                    else
                       app.ImageColorEditField.Value='RGB'; 
                    end
       
                catch 
                    warndlg('Corrupted Image! Please rebrowse.','Warning');
                end
              
             elseif (indx==4)
                fullname=[Pathname,Filename];
                app.PathEditField.Value=fullname;
                app.ModalityLabel.Visible='on';
                app.ModalityEditField.Visible='on';
                app.PatientNameLabel.Visible='on';
                app.PatientNameEditField.Visible='on';
                app.PatientAgeLabel.Visible='on';
                app.PatientAgeEditField.Visible='on';
                app.BodyPartExaminedLabel.Visible='on';
                app.BodyPartExaminedEditField.Visible='on';
                
                %try & catch error due to corrupted dicom image
                %Display dicom image with its image and header info
                try
                    ImageFile=dicomread(fullname);
                    app.ImageDisplay.Visible='on';
                    imshow(ImageFile,'Parent',app.ImageDisplay);
                    DicomImgData=dicominfo(fullname);
                    app.WidthEditField.Value=num2str(DicomImgData.Width);
                    app.HeightEditField.Value=num2str(DicomImgData.Height);
                    app.BitDepthEditField.Value=num2str(DicomImgData.BitDepth);
                    SizeinBitsdcm=double(DicomImgData.Width)*double(DicomImgData.Height)*double(DicomImgData.BitDepth);
                    app.SizeInBitsEditField.Value=num2str(SizeinBitsdcm);
                    app.ImageColorEditField.Value=(DicomImgData.ColorType);
                    app.PathEditField.Value=fullname;
                    app.ModalityEditField.Value=char(DicomImgData.Modality);

                    try
                        app.PatientAgeEditField.Value=num2str(DicomImgData.PatientAge); 
                    catch 
                        app.PatientAgeEditField.Value='Not Found';
                    end
                    
                    
                    try
                        app.BodyPartExaminedEditField.Value=char(DicomImgData.BodyPartExamined);
                    catch 
                        app.BodyPartExaminedEditField.Value='Not Found';
                    end
                    
 
                    try
                        app.PatientNameEditField.Value=struct2array(DicomImgData.PatientName);
                    catch 
                        app.PatientNameEditField.Value='Not Found';   
                    end
   
                catch 
                    Filename=0;
                   warndlg('Corrupted Dicom Image! Please rebrowse.','Warning');
                end
          
                
             else
                 Filename=0;
                warndlg('Please choose an image file');
             end
        end

        % Button pushed function: InterpolateButton
        function InterpolateButtonPushed(app, event)
global Filename;
global Pathname;
global indx;
global ImageFile;
            
            %Checks if there is an image chosen
            try
                fullname=[Pathname,Filename];
                % converts dicom to jpg
                if (indx==4)
                    dcmfile = dicomread(fullname);
                    dcmImage = uint8(255 * mat2gray(dcmfile));
                    folder='E:/CUFE/Fall_2022/Medical Image Processing & Computer Vision/Tasks/Task2-MariamOkasha';
                    imwrite(dcmImage,fullfile(folder,'dcmImage.jpg'));
                    fullname='E:/CUFE/Fall_2022/Medical Image Processing & Computer Vision/Tasks/Task2-MariamOkasha/dcmImage.jpg';
                end
                
                ImageFile=imread(fullname); 
                [row,col,channels]=size(ImageFile);
                %displays original dimensions
                app.OriginalWidthEditField.Value=num2str(col);
                app.OriginalHeightEditField.Value=num2str(row);
                        
                %Converts RGB to greyscale
                if channels==3
                    ImageFile= rgb2gray(ImageFile); 
                end
                
                app.OriginalImage.Position=[0,0,row,col];
                imshow(ImageFile,[],'Parent' ,app.OriginalImage); 
                
                app.ZoomingFactorLabel.Visible='on';
                app.ZoomingFactorEditField.Visible='on';
                app.InterpolationTypeDropDown.Visible='on';

            catch 
                warndlg('Please choose an image from "Image Viewer" Tab.','Warning');
            end       
            Filename=0;
        end

        % Value changed function: InterpolationTypeDropDown
        function InterpolationTypeDropDownValueChanged(app, event)
global ImageFile;        
            InterpolationType = app.InterpolationTypeDropDown.Value;
            
            [row, col] = size(ImageFile);

            ZoomingFactor=app.ZoomingFactorEditField.Value; 
            
            
            NewRowCount=round(ZoomingFactor*row);
            NewColCount=round(ZoomingFactor*col);
            
            app.NewWidthEditField.Value=num2str(NewColCount);
            app.NewHeightEditField.Value=num2str(NewRowCount);
            
            if (strcmp(InterpolationType,'Nearest-Neighbour Interpolation')==1)
                %Loop of row
                RowRange = 1 : NewRowCount;
                AlterRow = round(RowRange * (row - 1) / (NewRowCount - 1) + (NewRowCount - row) / (NewRowCount- 1)-0.1 );
                
                %Loop of col
                ColRange = 1 : NewColCount;
                AlterCol = round(ColRange * (col - 1) / (NewColCount - 1) + (NewColCount - col) / (NewColCount - 1)-0.1 ); 
                
                nearestinterpol(RowRange,ColRange) = ImageFile(AlterRow,AlterCol);
                app.InterpolatedImage.Position=[0,0,NewRowCount,NewColCount];
                imshow(nearestinterpol,[],'Parent' ,app.InterpolatedImage);
                app.InterpolatedImageLabel.Text = 'Nearest-Neighbour Interpolation';


            elseif (strcmp(InterpolationType,'Linear Interpolation')==1) 


                LinInterpol = zeros(NewRowCount,NewColCount);
              
                for i=1:NewRowCount
                    %row index of output 
                     y = (NewRowCount-i)/(NewRowCount-1)+row*((i-1)/(NewRowCount-1));
                   
                     for j=1:NewColCount 
                        %col index of output
                        x = (NewColCount-j)/(NewColCount-1)+col*((j-1)/(NewColCount-1));
                 
                        %Any values out of acceptable range
                        
                        x(x > col - 0.00001) = col - 0.00001;
                        x1 = floor(x);
                        x2 = x1 + 1;
                        
                        y(y > row - 0.00001) = row - 0.00001; 
                        y1 = floor(y);
                        y2 = y1 + 1;
                        
                        %// 4 Neighboring Pixels
                        NP1 = ImageFile(y1,x1);
                        NP2 = ImageFile(y1,x2);
                        NP3 = ImageFile(y2,x1); 
                        NP4 = ImageFile(y2,x2);
                        %// 4 Pixels Weights
                        PW1 = (y2-y)*(x2-x);
                        PW2 = (y2-y)*(x-x1);
                        PW3 = (x2-x)*(y-y1);
                        PW4 = (y-y1)*(x-x1);
                        LinInterpol(i,j) = PW1 * NP1 + PW2 * NP2 + PW3 * NP3 + PW4 * NP4;
                    end
                end
                app.InterpolatedImage.Position=[0,0,NewRowCount,NewColCount];


                imshow(LinInterpol,[],'Parent' ,app.InterpolatedImage);
                app.InterpolatedImageLabel.Text = 'Linear Interpolation';
                
            end

            
        end

        % Button pushed function: ConstructImageButton
        function ConstructImageButtonPushed(app, event)
            global TShape;
            app.AngleSlider.Value=0;
            app.InterpolationTypeOfRotatedImageDropDown.Value = app.InterpolationTypeOfRotatedImageDropDown.Items(1);
            
            TShape = zeros(128,128, 'uint8');
            TShape(29:49, 29:99) = 255;
            TShape(49:99, 54:74) = 255;
           
            imshow(TShape,'Parent' ,app.TImage); 
        end

        % Value changed function: AngleSlider
        function AngleSliderValueChanged(app, event)
            global TShape;
            global RotatedTShape;
            global angle;
            global xo;
            global yo;
            global midx;
            global midy;
            
            app.InterpolationTypeOfRotatedImageDropDown.Value=app.InterpolationTypeOfRotatedImageDropDown.Items(1);
            angle = app.AngleSlider.Value;
            
            [row,col]= size(TShape);

           
            NewRowCount=ceil(row*abs(cosd(angle))+col*abs(sind(angle)));                     
            NewColCount=ceil(row*abs(sind(angle))+col*abs(cosd(angle))); 
            
            app.RotateHeightEditField.Value=num2str(NewRowCount);
            app.RotateWidthEditField.Value=num2str(NewColCount);
            
            RotatedTShape=uint8(zeros([NewRowCount NewColCount]));
            
            %calculating center of original a image
            xo=ceil(row/2);                                                            
            yo=ceil(col/2);
            
            %calculating center of final image
            midx=ceil((size(RotatedTShape,1))/2); 
            midy=ceil((size(RotatedTShape,2))/2); 
            
            
            m=1;
            for i=1:size(TShape,1)
                for j=1:size(TShape,2)
                    %convert from cartesian to polar
                    [theta,r]=cart2pol(i-xo,j-yo);
                    %Convert from radians to degree and add the degree value
                    theta1=radtodeg(theta)+angle;
                    %Convert from degree to radians
                    theta=degtorad(theta1);
                    %Convert to Cartesian Co-ordinates
                    [x,y]=pol2cart(theta,r);
                    x1(m)=round(x+midx);
                    x2(m)=round(y+midy);
                  
                    m=m+1;
                end
            end
            %check whether the values are within the image size.
            x1(x1 < 1)=1;
            x2(x2 < 1)=1;
            
            n=1;
            for i=1:size(TShape,1)
                for j=1:size(TShape,2)
                    RotatedTShape(x1(n),x2(n),:)=TShape(i,j,:);
                   
                    n=n+1;
                end 
            end 
            
            imshow(RotatedTShape,'Parent' ,app.TImage);
            app.AngleEditField.Value=num2str(abs(angle));
            if (angle>0)
                app.DirectionEditField.Value="Anti-Clockwise";
            elseif (angle<0)
                app.DirectionEditField.Value="Clockwise";
            else
                app.DirectionEditField.Value="Zero";
            end
           
        end

        % Value changed function: 
        % InterpolationTypeOfRotatedImageDropDown
        function InterpolationTypeOfRotatedImageDropDownValueChanged(app, event)
            
            global TShape;  
            global angle;


            InterpolationTypeOfRotatedImage = app.InterpolationTypeOfRotatedImageDropDown.Value;
            [row, col] = size(TShape); 
            xo=ceil(row/2);                                                            
            yo=ceil(col/2);
            if (strcmp(InterpolationTypeOfRotatedImage,'Select Interpolation Method')==1)
              app.AngleSliderValueChanged();
              
            elseif (strcmp(InterpolationTypeOfRotatedImage,'Nearest-Neighbour Interpolation')==1)
                if (angle==0)
                   imshow(TShape,'Parent' ,app.TImage);
                else
                  
                   NewRowCountt=ceil(row*abs(cosd(angle))+col*abs(sind(angle))); 
                   NewColCountt=ceil(row*abs(sind(angle))+col*abs(cosd(angle))); 
                    
                   app.RotateHeightEditField.Value=num2str(NewRowCountt);
                   app.RotateWidthEditField.Value=num2str(NewColCountt);                


                    RotatedTShapee = uint8(zeros(NewRowCountt, NewColCountt));
                    for i=1:NewRowCountt
                        for j=1:NewColCountt                                                    
                    
                             x= (i-NewRowCountt/2)*cosd(angle) + (j-NewColCountt/2)*sind(angle);              
                             y= -(i-NewRowCountt/2)*sind(angle) + (j-NewColCountt/2)*cosd(angle);                           
                             x=round(x+xo); 
                             y=round(y+yo); 
                    
                             if (x>=1 && y>=1 && x<=size(TShape,1) &&  y<=size(TShape,2) ) 
                                  RotatedTShapee(i,j,:)=TShape(x,y,:); 
                             end
                        end
                    end
                     imshow(RotatedTShapee,'Parent' ,app.TImage);
                end


            elseif (strcmp(InterpolationTypeOfRotatedImage,'Linear Interpolation')==1)
                if (angle==0)
                    imshow(TShape,'Parent' ,app.TImage);
                else
                  
                   NewRowCount=ceil(row*abs(cosd(angle))+col*abs(sind(angle)));          
                   NewColCount=ceil(row*abs(sind(angle))+col*abs(cosd(angle)));           
                    
                   app.RotateHeightEditField.Value=num2str(NewRowCount);
                   app.RotateWidthEditField.Value=num2str(NewColCount);                
                    
                    
                    % Original needs to be the image I
                    RotatedTShapeee = uint8(zeros(NewRowCount, NewColCount));
                   
                    
                    for i = 1:NewRowCount
                        for j = 1:NewColCount
                             yo = (j - NewColCount/2) * cosd(angle) - (i - NewRowCount/2)*sind(angle)+64 ; 
                             xo = (j - NewColCount/2) * sind(angle) + (i - NewRowCount/2)*cosd(angle) +64; 
                    
                             x1 = floor(xo);  
                             y1 = floor(yo); 
                             a = xo - x1; 
                             b = yo - y1;
                             
                             % check if the coordinate is in the original image to prevent errors
                             if x1 > 0  && y1 > 0 && y1 <= col && x1 <= row
                                 RotatedTShapeee(i, j) = RotatedTShapeee(i, j) + (1-a)*(1-b)*TShape(x1,y1);
                             end
                             if x1 > 0  && y1+1 > 0 && y1+1 <= col && x1 <= row
                                 RotatedTShapeee(i, j) = RotatedTShapeee(i, j) + (1-a)*b*TShape(x1,y1+1);
                             end
                             if x1+1 > 0  && y1 > 0 && y1 <= col && x1+1 <= row
                                 RotatedTShapeee(i, j) = RotatedTShapeee(i, j) + a*(1-b)*TShape(x1+1,y1);
                             end
                             if x1+1 > 0  && y1+1 > 0 && y1+1 <= col && x1+1 <= row
                                 RotatedTShapeee(i, j) = RotatedTShapeee(i, j) + a*b*TShape(x1+1,y1+1);
                             end
                        end
                    end
                    imshow(RotatedTShapeee,'Parent' ,app.TImage);
                end
           
            end        
        end

        % Button pushed function: Shear45Button
        function Shear45ButtonPushed(app, event)
              global TShape;
              [row,col]= size(TShape);
              newCol=col+row-1;
              
             shear=zeros([row newCol]);
             
              for i=1:row
                 shearedCol=newCol-i+1;
                 
                 for j=shearedCol-col+1:shearedCol
                    
                     shear(i,j)=TShape(i,(j-col+i));
                    
                 end
             end
             imshow(shear,'Parent' ,app.TImage);
        end

        % Button pushed function: ShearNegative45Button
        function ShearNegative45ButtonPushed(app, event)

              global TShape;
              [row,col]= size(TShape);
              newCol=col+row-1;
              
             shear=zeros([row newCol]);
             for i=1:row
                 shearedCol=i+col-1;
                 for j=i:shearedCol
                    
                     shear(i,j)=TShape(i,(j-i+1));
                    
                 end
             end
             imshow(shear,'Parent' ,app.TImage);
        end

        % Button pushed function: HistogramBrowseButton
        function HistogramBrowseButtonPushed(app, event)
            [Filename, Pathname,indx] = uigetfile({'*.jpg';'*.jpeg';'*.bmp';'*.dcm';'*.tif';'*.png'}, 'All Files');
             
            if Filename==0
                return;    
            end
            
            fullname=[Pathname,Filename];
            if (indx==4)
                dcmfile = dicomread(fullname);
                dcmImage = uint8(255 * mat2gray(dcmfile));
                folder='E:/CUFE/Fall_2022/Medical Image Processing & Computer Vision/Tasks/Task4-MariamOkasha/Images';
                imwrite(dcmImage,fullfile(folder,'dcmImage.jpg'));
                fullname='E:/CUFE/Fall_2022/Medical Image Processing & Computer Vision/Tasks/Task4-MariamOkasha/Images/dcmImage.jpg';
            end
       
            try
                ImageFile=imread(fullname);
                [row, col, channels] = size(ImageFile);
                  
                if channels==3
                    ImageFile= rgb2gray(ImageFile);
                end
                imshow(ImageFile,'Parent' ,app.UnequalizedDisplay);
                
                %%%%%%%%%%%%%%%%%%%%%%normalized histogram%%%%%%%%%%%%%%%%%%%%%%%
                
                freq=zeros(256,1);
                normalized=zeros(256,1);
           
                for i=1:row
                    for j=1:col 
    	                pix=ImageFile(i,j);
    	                freq(pix+1)=freq(pix+1)+1;                               
                        normalized(pix+1)=freq(pix+1)/(row*col);                        
                    end
                end
                
                bar(app.UnequalizedHistogram,normalized);
                
                %%%%%%%%%%%%%%%%%%%%%%equalization%%%%%%%%%%%%%%%%%%%%%%%
                
                EqualizedImageFile = uint8(zeros(row,col));
                cdf = zeros(256,1);
                cumulative = zeros(256,1);
                output = zeros(256,1);
                
                s =0 ;
                intensityLevel = 255;
                
                for i=1:size(normalized)
                   s=s+freq(i);                
                   cumulative(i)=s;                
                   cdf(i)=cumulative(i)/(row*col);                
                   output(i)=round(cdf(i)*intensityLevel);                
                end
                
                for i=1:row               
                    for j=1:col
                         EqualizedImageFile(i,j)=output(ImageFile(i,j)+1);                
                    end                
                end  
                
                imshow(EqualizedImageFile,'Parent' ,app.EqualizedDisplay);
                
                %%%%%%%%%%%%%%%%%%%%%%equalization histogram%%%%%%%%%%%%%%%%%%%%%%%
                
                [eqrow, eqcol] = size(ImageFile);
                eqfreq=zeros(256,1);
                eqnormalized=zeros(256,1);
           
                for i=1:eqrow
                    for j=1:eqcol
    	                eqpix=EqualizedImageFile(i,j);
    	                eqfreq(eqpix+1)=eqfreq(eqpix+1)+1;                               
                        eqnormalized(eqpix+1)=eqfreq(eqpix+1)/(eqrow*eqcol);                        
                    end
                end
                
                bar(app.EqualizedHistogram,eqnormalized); 
                
                
            catch 
                warndlg('Corrupted Image! Please rebrowse.','Warning');
            end
        end

        % Button pushed function: SpatialFilterBrowseButton
        function SpatialFilterBrowseButtonPushed(app, event)
          global ImageFile;
            [Filename, Pathname,indx] = uigetfile({'*.jpg';'*.jpeg';'*.bmp';'*.dcm';'*.tif';'*.png'}, 'All Files');
             
            if Filename==0
                return;    
            end
            
            fullname=[Pathname,Filename];
            if (indx==4)
                dcmfile = dicomread(fullname);
                dcmImage = uint8(255 * mat2gray(dcmfile));
                folder='E:/CUFE/Fall_2022/Medical Image Processing & Computer Vision/Tasks/Task4-MariamOkasha/Images';
                imwrite(dcmImage,fullfile(folder,'dcmImage.jpg'));
                fullname='E:/CUFE/Fall_2022/Medical Image Processing & Computer Vision/Tasks/Task4-MariamOkasha/Images/dcmImage.jpg';
            end
       
            try
                ImageFile=imread(fullname);
                [row, col, channels] = size(ImageFile);
                  
                if channels==3
                    ImageFile= rgb2gray(ImageFile);
                end
             imshow(ImageFile,'Parent' ,app.UnfilteredImageDisplay);
             app.RemoveNoiseButton.Visible = 'on';
             app.EnhanceButton.Visible = 'on';
            catch 
                warndlg('Corrupted Image! Please rebrowse.','Warning');
            end
        end

        % Button pushed function: EnhanceButton
        function EnhanceButtonPushed(app, event)
           global ImageFile;
           
            [row,col]=size(ImageFile);
            Output=uint8(zeros(row,col));
            
            kernelValue=app.KernelSizeSpinner.Value;
            kernel=(ones(kernelValue))/(kernelValue*kernelValue);
            [krow,kcol]=size(kernel);
            
            if(size(kernel)>size(ImageFile))
                warndlg('kernel size greater than image size','Warning');
                return;
            end
            %kernel midpoints
            kerMidRow = ceil(krow/2); 
            kerMidCol = ceil(kcol/2);
            
            %padding needed
            zeropad=(kernelValue-1)/2;
            Padding=zeros(row+krow-1,col+kcol-1);
            for i=1:row
                for j=1:col
                        Padding(i+zeropad,j+zeropad)=ImageFile(i,j);
                end
            end
            
            %convolution
            for NewImgRow = kerMidRow : size(Padding,1) - (kerMidRow - 1)
                 for NewImgCol = kerMidCol : size(Padding,2) - (kerMidCol - 1)
                     s = 0;
                     for kerRow = 1 : krow
                         for kerCol = 1 : kcol
                             multVal = kernel(kerRow, kerCol) * Padding((kerRow - kerMidRow + NewImgRow), (kerCol - kerMidCol + NewImgCol)); 
                             s = s + multVal;
                         end
                     end
                     
                     Output((NewImgRow - kerMidRow + 1), (NewImgCol - kerMidCol + 1)) = s;
                 end
            end
            app.BlurredNoisyImageLabel.Text = 'Blurred Image';
            imshow(Output,'Parent' ,app.Blurred_NoisyDisplay);
            
            %enhanced image
            kFactor=app.FactorKEditField.Value;
            enhancedImage=(kFactor*(ImageFile-Output))+ImageFile;
            ScaleFcn(app, enhancedImage);
            app.EnhancedImageLabel.Text = 'Enhanced Image';
            imshow(enhancedImage,'Parent' ,app.EnhancedImageDisplay);

            
        end

        % Button pushed function: RemoveNoiseButton
        function RemoveNoiseButtonPushed(app, event)
            global ImageFile;
            
            [row,col]=size(ImageFile);
            
            % add noise 
            for i=1:row
                for j=1:col
                 NoisyImage(i,j)=ImageFile(i,j);
                end
            end
            
            
%             for loop=1:(row*col*0.03)
%                 i=(ceil(rand*row));
%                 j=(ceil(rand*col));
%                 NoisyImage(i,j)=255;
%             end
%             for loop=1:(row*col*0.03)
%                 i=(ceil(rand*row));
%                 j=(ceil(rand*col));
%                 NoisyImage(i,j)=0;
%             end
            app.BlurredNoisyImageLabel.Text = 'Noisy Image';
            imshow(NoisyImage,'Parent' ,app.Blurred_NoisyDisplay);
            
            % remove noise
            NoiseRemoved=uint8(zeros(row,col));
            
            kernelValue=app.KernelSizeSpinner.Value;
            kernel=ones(kernelValue);
            [krow,kcol]=size(kernel);
            
            if(size(kernel)>size(NoisyImage))
                warndlg('kernel size greater than image size','Warning');
                return;
            end
            
            kerMidRow = ceil(krow/2); 
            kerMidCol = ceil(kcol/2);
            
            zeropad=(kernelValue-1)/2;
            Padding=zeros(row+krow-1,col+kcol-1);
            for i=1:row
                for j=1:col
                        Padding(i+zeropad,j+zeropad)=NoisyImage(i,j);
                end
            end
            
            for NewImgRow = kerMidRow : size(Padding,1) - (kerMidRow - 1)
                 for NewImgCol = kerMidCol : size(Padding,2) - (kerMidCol - 1)
                     element=zeros(kernelValue*kernelValue,1);
                     for kerRow = 1 : krow
                         for kerCol = 1 : kcol
                             element(krow*(kerCol-1)+kerRow)=Padding((kerRow - kerMidRow + NewImgRow), (kerCol - kerMidCol + NewImgCol));
                         end
                     end
                     %%%%%%%%%%hangib el median hna
                     sortedValues = zeros(size(element));          
                     for k = 1:numel(element)           
                        [m, ind] = min(element);       
                        element(ind)   = [];            
                        sortedValues(k)= m;
                     end
                     median=sortedValues(ceil(numel(sortedValues)/2));
                     NoiseRemoved((NewImgRow - kerMidRow + 1), (NewImgCol - kerMidCol + 1)) = median;
                 end
            end
            app.EnhancedImageLabel.Text = 'Denoised Image';
            imshow(NoiseRemoved,'Parent' ,app.EnhancedImageDisplay);
        end

        % Button pushed function: FourierBrowseButton
        function FourierBrowseButtonPushed(app, event)
           global fourierWithoutLog;
           global fourierWithLog;
         
            [Filename, Pathname,indx] = uigetfile({'*.jpg';'*.jpeg';'*.bmp';'*.dcm';'*.tif';'*.png'}, 'All Files');
               
            if Filename==0
                return;    
            end
            
            fullname=[Pathname,Filename];
            if (indx==4)
                dcmfile = dicomread(fullname);
                dcmImage = uint8(255 * mat2gray(dcmfile));
                folder='E:/CUFE/Fall_2022/Medical Image Processing & Computer Vision/Tasks/Task4-MariamOkasha/Images';
                imwrite(dcmImage,fullfile(folder,'dcmImage.jpg'));
                fullname='E:/CUFE/Fall_2022/Medical Image Processing & Computer Vision/Tasks/Task4-MariamOkasha/Images/dcmImage.jpg';
            end
       
             try
                ImageFile=imread(fullname);
                [row, col, channels] = size(ImageFile);
                  
                if channels==3
                    ImageFile= rgb2gray(ImageFile);
                end
                imshow(ImageFile,'Parent' ,app.OriginalImageDisplay);
                
                
                % Apply Fourier transform to the image
                fourierWithoutLog=1+fftshift(fft2(ImageFile));
                fourierWithLog=log(fourierWithoutLog);
            
                %get magnitude  
                magnitudeW=abs(fourierWithLog);
                for i=1:size(magnitudeW,1)
                    for j=1:size(magnitudeW,2)
                        
                       if magnitudeW(i,j) > 255
                           magnitudeW(i,j)=255;
                       end
                       if magnitudeW(i,j) < 0
                           magnitudeW(i,j)=0;
                       end 
                       
                    end
                end
                imshow(magnitudeW,[],'Parent' ,app.MagnitudeDisplay);
                app.MagnitudeLabel.Text = 'Magnitude With Log';
                
                %get phase
                phaseW=angle(fourierWithLog);
                for i=1:size(phaseW,1)
                    for j=1:size(phaseW,2)
                        
                       if phaseW(i,j) > 255
                           phaseW(i,j)=255;
                       end
                       if phaseW(i,j) < 0
                           phaseW(i,j)=0;
                       end 
                       
                    end
                end
                imshow(phaseW,[],'Parent' ,app.PhaseDisplay);    
                app.PhaseLabel.Text = 'Phase With Log';        
            catch 
                warndlg('Corrupted Image! Please rebrowse.','Warning');
            end             
        end

        % Value changed function: LogDropDown
        function LogDropDownValueChanged(app, event)
           global fourierWithoutLog;
           global fourierWithLog;
            value = app.LogDropDown.Value;
            
            
            if (strcmp(value,'With Log')==1)
                %get magnitude
                magnitudeW=abs(fourierWithLog);
                for i=1:size(magnitudeW,1)
                    for j=1:size(magnitudeW,2)
                        
                       if magnitudeW(i,j) > 255
                           magnitudeW(i,j)=255;
                       end
                       if magnitudeW(i,j) < 0
                           magnitudeW(i,j)=0;
                       end 
                       
                    end
                end
                imshow(magnitudeW,[],'Parent' ,app.MagnitudeDisplay);
                app.MagnitudeLabel.Text = 'Magnitude With Log';
                %get phase
                phaseW=angle(fourierWithLog);
                for i=1:size(phaseW,1)
                    for j=1:size(phaseW,2)
                        
                       if phaseW(i,j) > 255
                           phaseW(i,j)=255;
                       end
                       if phaseW(i,j) < 0
                           phaseW(i,j)=0;
                       end 
                       
                    end
                end
                imshow(phaseW,[],'Parent' ,app.PhaseDisplay);
                app.PhaseLabel.Text = 'Phase With Log';  
                
            elseif (strcmp(value,'Without Log')==1)
                %get magnitude
                magnitude=abs(fourierWithoutLog);
                for i=1:size(magnitude,1)
                    for j=1:size(magnitude,2)
                        
                       if magnitude(i,j) > 255
                           magnitude(i,j)=255;
                       end
                       if magnitude(i,j) < 0
                           magnitude(i,j)=0;
                       end 
                       
                    end
                end
                imshow(magnitude,[],'Parent' ,app.MagnitudeDisplay);
                app.MagnitudeLabel.Text = 'Magnitude Without Log';
                %get phase
                phase=angle(fourierWithoutLog);
                for i=1:size(phase,1)
                    for j=1:size(phase,2)
                        
                       if phase(i,j) > 255
                           phase(i,j)=255;
                       end
                       if phase(i,j) < 0
                           phase(i,j)=0;
                       end 
                       
                    end
                end
                imshow(phase,[],'Parent' ,app.PhaseDisplay);
                app.PhaseLabel.Text = 'Phase Without Log';
            end
        end

        % Button pushed function: FourierIIBrowseButton
        function FourierIIBrowseButtonPushed(app, event)
          global ImageFileF;
            [Filename, Pathname,indx] = uigetfile({'*.jpg';'*.jpeg';'*.bmp';'*.dcm';'*.tif';'*.png'}, 'All Files');
             
            if Filename==0
                return;    
            end
            
            fullname=[Pathname,Filename];
            if (indx==4)
                dcmfile = dicomread(fullname);
                dcmImage = uint8(255 * mat2gray(dcmfile));
                folder='E:/CUFE/Fall_2022/Medical Image Processing & Computer Vision/Tasks/Task4-MariamOkasha/Images';
                imwrite(dcmImage,fullfile(folder,'dcmImage.jpg'));
                fullname='E:/CUFE/Fall_2022/Medical Image Processing & Computer Vision/Tasks/Task4-MariamOkasha/Images/dcmImage.jpg';
            end
       
            try
                ImageFileF=imread(fullname);
                [row, col, channels] = size(ImageFileF);
                  
                if channels==3
                    ImageFileF= rgb2gray(ImageFileF);
                end
             imshow(ImageFileF,'Parent' ,app.ImageDisplaay);
             
           %padding even images 
            if(rem(col,2)==0)
                ImageFileF(:,end+1)=ImageFileF(:,end);
            end
            if(rem(row,2)==0)
                ImageFileF(end+1,:)=ImageFileF(end,:);
            end 
            [row,col]=size(ImageFileF);
            
            catch 
                warndlg('Corrupted Image! Please rebrowse.','Warning');
            end            
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            global ImageFileF;
            
            [row,col]=size(ImageFileF);
            kernelValue=app.FourierKernelSizeSpinner.Value;
            kernel=ones(kernelValue)/(kernelValue*kernelValue);
            [krow,kcol]=size(kernel);
            
            if(size(kernel)>size(ImageFileF))
                warndlg('kernel size greater than image size','Warning');
                return;
            end
            Padding=zeros(row,col);
            for i=1:kernelValue
                for j=1:kernelValue
                        Padding(i+round((row-kernelValue)/2),j+round((col-kernelValue)/2))=kernel(i,j);
                end
            end
            
            FourierImage=fftshift((fft2(ImageFileF)));
            FourierFilter=fftshift((fft2(Padding)));
            Mult=FourierImage.*FourierFilter;
            Fourier=uint8(abs(ifftshift(ifft2(Mult))));
            imshow(Fourier,'Parent' ,app.FourierFilterDisplay);
            
         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Output=uint8(zeros(row,col));
            kerMidRow = ceil(krow/2); 
            kerMidCol = ceil(kcol/2);
            
            %padding needed
            zeropad=(kernelValue-1)/2;
            PaddingSpatial=zeros(row+krow-1,col+kcol-1);
            for i=1:row
                for j=1:col
                        PaddingSpatial(i+zeropad,j+zeropad)=ImageFileF(i,j);
                end
            end
            %convolution
            for NewImgRow = kerMidRow : size(PaddingSpatial,1) - (kerMidRow - 1)
                 for NewImgCol = kerMidCol : size(PaddingSpatial,2) - (kerMidCol - 1)
                     s = 0;
                     for kerRow = 1 : krow
                         for kerCol = 1 : kcol
                             multVal = kernel(kerRow, kerCol) * PaddingSpatial((kerRow - kerMidRow + NewImgRow), (kerCol - kerMidCol + NewImgCol)); 
                             s = s + multVal;
                         end
                     end
                     
                     Output((NewImgRow - kerMidRow + 1), (NewImgCol - kerMidCol + 1)) = s;
                 end
            end   
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           sub=Fourier-Output 
           imshow(sub,'Parent' ,app.DifferenceDisplay);
        end

        % Callback function
        function DifferenceButtonPushed(app, event)
           global ImageFile;
           
            [row,col]=size(ImageFile);
            Output=uint8(zeros(row,col));
            
            kernelValue=app.KernelSizeSpinner.Value;
            kernel=(ones(kernelValue))/(kernelValue*kernelValue);
            [krow,kcol]=size(kernel);
            kerMidRow = ceil(krow/2); 
            kerMidCol = ceil(kcol/2);
            
            %padding needed
            zeropad=(kernelValue-1)/2;
            Padding=zeros(row+krow-1,col+kcol-1);
            for i=1:row
                for j=1:col
                        Padding(i+zeropad,j+zeropad)=ImageFile(i,j);
                end
            end
            
            %convolution
            for NewImgRow = kerMidRow : size(Padding,1) - (kerMidRow - 1)
                 for NewImgCol = kerMidCol : size(Padding,2) - (kerMidCol - 1)
                     s = 0;
                     for kerRow = 1 : krow
                         for kerCol = 1 : kcol
                             multVal = kernel(kerRow, kerCol) * Padding((kerRow - kerMidRow + NewImgRow), (kerCol - kerMidCol + NewImgCol)); 
                             s = s + multVal;
                         end
                     end
                     
                     Output((NewImgRow - kerMidRow + 1), (NewImgCol - kerMidCol + 1)) = s;
                 end
            end
        end

        % Button pushed function: RemovePatternButton
        function RemovePatternButtonPushed(app, event)
            fullname='E:/CUFE/Fall_2022/Medical Image Processing & Computer Vision/Tasks/Task6.2-MariamOkasha/Pattern.jpeg';
            
            ImageFile=imread(fullname);
            [row, col, channels] = size(ImageFile);
            if channels==3
                ImageFile= rgb2gray(ImageFile);
            end
            imshow(ImageFile,'Parent' ,app.ImageWithPattern);
            
            frequencyImage = fftshift(fft2(ImageFile));
            % Take log magnitude so we can see it better in the display.


            frequencyImage(482:497,320:335 ) = 0;
            frequencyImage(480:495,375:390) = 0;
            frequencyImage(564:579,322:337 ) = 0;
            frequencyImage(559:574,375:390) = 0; 
            
            
            frequencyImage(447:462,320:335 ) = 0;
            frequencyImage(445:460,375:390) = 0;
            frequencyImage(599:614,322:337 ) = 0;
            frequencyImage(594:609,375:390) = 0; 

            amplitudeImage = log(abs(frequencyImage));
            filteredImage = ifft2(fftshift(frequencyImage));
            amplitudeImage2 = abs(filteredImage);


            imshow(amplitudeImage,[],'Parent' ,app.Mask);
            imshow(amplitudeImage2, [],'Parent' ,app.ImageWithoutPattern);
                      
        end

        % Button pushed function: CreateImageButton
        function CreateImageButtonPushed(app, event)
           global Image;
            Image = 50*ones(256,256, 'uint8');
            Image(35:222,35:222)=150;
            [X,Y] = meshgrid(1:256,1:256) ;
            circle=(X-128.5).^2+(Y-128.5).^2<=60.^2;
            Image(circle)=250;
            app.CreatedImage.Position=[35,85,291,341];
          
            imshow(Image,'Parent' ,app.CreatedImage);
            
            disableDefaultInteractivity(app.CreatedImage)
        end

        % Value changed function: NoiseTypeDropDown
        function NoiseTypeDropDownValueChanged(app, event)
            global OriginalImage;
            global Image;
            global roi;
            global Gaussian_noise;
            global Uniform_noise;
            value = app.NoiseTypeDropDown.Value;
            if (strcmp(value,'No Noise')==1)
                OriginalImage=uint8(Image);
                imshow(OriginalImage,'Parent' ,app.CreatedImage);                 
%                 roi=drawrectangle(app.CreatedImage);
                
            elseif (strcmp(value,'Gaussian Noise')==1)
                
                Gaussian_noise=uint8((sqrt(5^2)*randn(size(Image)))+0+double(Image));
                imshow(Gaussian_noise,'Parent' ,app.CreatedImage); 
%                 roi=drawrectangle(app.CreatedImage);
                
            elseif (strcmp(value,'Uniform Noise')==1)
                a = -10;
                b = 10; 
                Uniform_noise=uint8(a+(b-a)*rand(size(Image))+double(Image));
                imshow(Uniform_noise,'Parent' ,app.CreatedImage); 
%                 roi=drawrectangle(app.CreatedImage);
            end
            roi=drawrectangle(app.CreatedImage);
        end

        % Button pushed function: ConfirmRegionofInterestButton
        function ConfirmRegionofInterestButtonPushed(app, event)
            global roi;
            global OriginalImage;
            global Gaussian_noise;
            global Uniform_noise;
              try
                value = app.NoiseTypeDropDown.Value;
                roi.InteractionsAllowed='none';
                roi_xy=roi.Position;
                x1=round(roi_xy(1));
                x2=round(x1+roi_xy(3));
                y1=round(roi_xy(2));
                y2=round(y1+roi_xy(4)); 
                
                if (strcmp(value,'No Noise')==1)
                    region=OriginalImage(y1:y2,x1:x2);
                    disp(region);
                elseif (strcmp(value,'Gaussian Noise')==1)
                    region=Gaussian_noise(y1:y2,x1:x2); 
                    disp(region);
                elseif (strcmp(value,'Uniform Noise')==1)
                    region=Uniform_noise(y1:y2,x1:x2);
                    
                end
                histo=zeros(1,256);
                [j,k]=size(region);
                for row=1:j
                    for col=1:k
                        
                        pix=region(row,col);
                        histo(pix)=histo(pix)+1;
                        normalized(pix)=histo(pix)/(k*j);
                
                    end
                end 
                 
                s=0;count=0;sumexp=0;
                bar(app.ROIHistogram,normalized);
                for i=1:length(histo)
                    s=s+i*histo(i);
                    sumexp=sumexp+i^2*histo(i);
                    count=count+histo(i);
                end
                mean=s/count;
                Exp=sumexp/count;
                variance=sqrt(Exp-mean^2);
                app.MeanEditField.Value=num2str(mean);
                app.VarianceEditField.Value=num2str(variance);
             catch
                 warndlg('ROI must be inside the Image');
             end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global lam;
            global I2;
            global I3;
            global I4;
            
            Pt = phantom(256);
            imshow(Pt,[],'Parent',app.ScheppLoganPhantom);
            
            theta=0:179;
            an=[0,20,40,60,160];
            
            sino = radon(Pt, theta);
            imshow(rot90(sino),[],'Parent',app.Sinogram);
            
            lam=iradon(radon(Pt,an),an,'linear','none');
            imshow(lam,[],'Parent',app.Laminogram);
            I2 = iradon(sino,theta,'linear','none');
            I3 = iradon(sino, theta, 'linear', 'Ram-Lak');
            I4 = iradon(sino, theta, 'linear', 'Hamming');
            
        end

        % Value changed function: LaminogramDropDown
        function LaminogramDropDownValueChanged(app, event)
            global lam;
            global I2;
            global I3;
            global I4;            
            value = app.LaminogramDropDown.Value;
          
            if (strcmp(value,'Unfiltered Laminogram (0,20,40,60,160)')==1)

                imshow(lam,[],'Parent',app.Laminogram); 
                
            elseif (strcmp(value,'UnFiltered Laminogram (0:179)')==1)
                
                imshow(I2,[],'Parent',app.Laminogram);
                
            elseif (strcmp(value,'RamLak')==1)
                
                imshow(I3,[],'Parent',app.Laminogram);
                
            elseif (strcmp(value,'Hamming')==1)
                
                imshow(I4,[],'Parent',app.Laminogram);
            end
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            Pt = phantom(256);
            [x,y] = size(Pt);
            %Set a matrix R to hold the projection data
            R = zeros((180),x);
            %Set vector for the angles to take projections at
            angles = 0:179;
            %Matrix to hold all the rotated images separately to prevent blurring
            rotatedImage = zeros(x,y,length(angles));
        
            %Loop to rotate image and add up values for the projections
            for i = 1:(length(angles))
                %rotate the image starting with theta = 0 degrees
                rotatedImage(:,:,i) = imrotate(Pt,angles(i),'nearest','crop');
                %Sum the columns of img to get projection data
                %Each row of R contains a projection at a certain theta
                R(i,:) = sum(rotatedImage(:,:,i),1);
            end

            %Convert the matrix to a gray scale image in the range 0 to 255
            R = mat2gray(R);

            imshow(flip(R,2),[],'Parent',app.Sinogram);
            

        end

        % Button pushed function: BuiltinButton
        function BuiltinButtonPushed(app, event)
            Pt = phantom(256);
            sino = radon(Pt);
            imshow(rot90(sino),[],'Parent',app.Sinogram);            
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            global StructElement;
            global ImageFile;
            fullname='E:/CUFE/Fall_2022/Medical Image Processing & Computer Vision/Tasks/Task9-MariamOkasha/binary_image.png';
            ImageFile=imread(fullname);
            ImageFile= imbinarize(rgb2gray(ImageFile));
            imshow(ImageFile,'Parent' ,app.FingerprintImage);
            StructElement = zeros(5,5);
            StructElement(2:4, 1:5) = 1;
            StructElement(1:5, 2:4) = 1;
            imshow(StructElement,'Parent' ,app.MorphologicalImage);  
    
        end

        % Button pushed function: ErosionButton
        function ErosionButtonPushed(app, event)
            global StructElement;
            global ImageFile;
            [row,col]=size(ImageFile);  
            imgErode= zeros(row,col);
            
            for i=3:row-2
                for j=3:col-2
                    s=0;
                    temp= ImageFile(i-2:i+2, j-2:j+2);
                    product= temp.*StructElement;
                    s=sum(product,"all");
                    if (s==21)
                        imgErode(i,j)= 1;
                    end
                    
                end
            end
            imshow((imgErode),'Parent' ,app.MorphologicalImage); 
        end

        % Button pushed function: DilationButton
        function DilationButtonPushed(app, event)
            global StructElement;
            global ImageFile;
            [row,col]=size(ImageFile);  
            imgDilate= zeros(row,col);
            Padding=zeros(row+4,col+4);
            [newrow,newcol]=size(Padding);
            for i=1:row
                for j=1:col
                        Padding(i+2,j+2)=ImageFile(i,j);
                end
            end
            
            for i=3:newrow-2
                for j=3:newcol-2
                    temp= Padding(i-2:i+2, j-2:j+2);
                    product= temp.*StructElement;
                    imgDilate(i-2,j-2)= max(max(product));
                end
            end
            
            imshow((imgDilate),'Parent' ,app.MorphologicalImage);     
        end

        % Button pushed function: OpeningButton
        function OpeningButtonPushed(app, event)
            global StructElement;
            global ImageFile;
            [row,col]=size(ImageFile);  
            imgErode= zeros(row,col);
            imgOpening= zeros(row,col);
            Padding=zeros(row+4,col+4);
            [newrow,newcol]=size(Padding);
            
            for i=3:row-2
                for j=3:col-2
                    s=0;
                    temp= ImageFile(i-2:i+2, j-2:j+2);
                    product= temp.*StructElement;
                    s=sum(product,"all");
                    if (s==21)
                        imgErode(i,j)= 1;
                    end
                    
                end
            end
            
            for i=1:row
                for j=1:col
                        Padding(i+2,j+2)=imgErode(i,j);
                end
            end
            
            for i=3:newrow-2
                for j=3:newcol-2
                    temp= Padding(i-2:i+2, j-2:j+2);
                    product= temp.*StructElement;
                    imgOpening(i-2,j-2)= max(max(product));
                end
            end
            
            imshow((imgOpening),'Parent' ,app.MorphologicalImage);             
            
        end

        % Button pushed function: ClosingButton
        function ClosingButtonPushed(app, event)
            global StructElement;
            global ImageFile;
            [row,col]=size(ImageFile);  
            imgDilate= zeros(row,col);
            imgClosing= zeros(row,col);
            Padding=zeros(row+4,col+4);
            [newrow,newcol]=size(Padding);  
            for i=1:row
                for j=1:col
                        Padding(i+2,j+2)=ImageFile(i,j);
                end
            end
            
            for i=3:newrow-2
                for j=3:newcol-2
                    temp= Padding(i-2:i+2, j-2:j+2);
                    product= temp.*StructElement;
                    imgDilate(i-2,j-2)= max(max(product));
                end
            end
            
            for i=3:row-2
                for j=3:col-2
                    s=0;
                    temp= imgDilate(i-2:i+2, j-2:j+2);
                    product= temp.*StructElement;
                    s=sum(product,"all");
                    if (s==21)
                        imgClosing(i,j)= 1;
                    end
                    
                end
            end  
            imshow((imgClosing),'Parent' ,app.MorphologicalImage);             
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global ImageFile;
            StructElement = zeros(3,3);
            StructElement(2, 1:3) = 1;
            StructElement(1:3, 2) = 1; 
            [row,col]=size(ImageFile);  
            imgErode= zeros(row,col);
            imgOpening= zeros(row,col);
            imgDilate= zeros(row,col);
            imgClosing= zeros(row,col);
            Padding1=zeros(row+2,col+2);
            Padding2=zeros(row+2,col+2);
            [newrow,newcol]=size(Padding1);
            
            for i=2:row-1
                for j=2:col-1
                    s=0;
                    temp= ImageFile(i-1:i+1, j-1:j+1);
                    product= temp.*StructElement;
                    s=sum(product,"all");
                    if (s==5)
                        imgErode(i,j)= 1;
                    end
                    
                end
            end
            
            for i=1:row
                for j=1:col
                        Padding1(i+1,j+1)=imgErode(i,j);
                end
            end
            
            for i=2:newrow-1
                for j=2:newcol-1
                    temp= Padding1(i-1:i+1, j-1:j+1);
                    product= temp.*StructElement;
                    imgOpening(i-1,j-1)= max(max(product));
                end
            end  
            imshow((imgOpening),'Parent' ,app.MorphologicalImage);
            
            for i=1:row
                for j=1:col
                        Padding2(i+1,j+1)=imgOpening(i,j);
                end
            end
            
            for i=2:newrow-1
                for j=2:newcol-1
                    temp= Padding2(i-1:i+1, j-1:j+1);
                    product= temp.*StructElement;
                    imgDilate(i-1,j-1)= max(max(product));
                end
            end 
            
            for i=2:row-1
                for j=2:col-1
                    s=0;
                    temp= imgDilate(i-1:i+1, j-1:j+1);
                    product= temp.*StructElement;
                    s=sum(product,"all");
                    if (s==5)
                        imgClosing(i,j)= 1;
                    end
                    
                end
            end 
            imshow((imgClosing),'Parent' ,app.MorphologicalImage);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global ImageFile;
            StructElement = ones(1,3);
    
            [row,col]=size(ImageFile);  
            imgErode= zeros(row,col);
            imgOpening= zeros(row,col);
            imgDilate= zeros(row,col);
            imgClosing= zeros(row,col);
            Padding1=zeros(row,col+2);
            Padding2=zeros(row,col+2);
            [newrow,newcol]=size(Padding1);
            
            for i=1:row
                for j=2:col-1
                    s=0;
                    temp= ImageFile(i:i, j-1:j+1);
                    product= temp.*StructElement;
                    s=sum(product,"all");
                    if (s==3)
                        imgErode(i,j)= 1;
                    end
                    
                end
            end
            
            for i=1:row
                for j=1:col
                        Padding1(i,j+1)=imgErode(i,j);
                end
            end
            
            for i=1:newrow
                for j=2:newcol-1
                    temp= Padding1(i:i, j-1:j+1);
                    product= temp.*StructElement;
                    imgOpening(i,j-1)= max(max(product));
                end
            end  
            imshow((imgOpening),'Parent' ,app.MorphologicalImage);
            
            for i=1:row
                for j=1:col
                        Padding2(i,j+1)=imgOpening(i,j);
                end
            end
            
            for i=1:newrow
                for j=2:newcol-1
                    temp= Padding2(i:i, j-1:j+1);
                    product= temp.*StructElement;
                    imgDilate(i,j-1)= max(max(product));
                end
            end 
            
            for i=1:row
                for j=2:col-1
                    s=0;
                    temp= imgDilate(i:i, j-1:j+1);
                    product= temp.*StructElement;
                    s=sum(product,"all");
                    if (s==3)
                        imgClosing(i,j)= 1;
                    end
                    
                end
            end 
            imshow((imgClosing),'Parent' ,app.MorphologicalImage);       
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            global ImageFile;
            global imgClosinggg;
            StructElement = ones(3,3);
            StructElement(1, 1) = 0;
            StructElement(3, 3) = 0; 
            [row,col]=size(ImageFile);  
            imgErode= zeros(row,col);
            imgOpening= zeros(row,col);
            imgDilate= zeros(row,col);
            imgClosinggg= zeros(row,col);
            Padding1=zeros(row+2,col+2);
            Padding2=zeros(row+2,col+2);
            [newrow,newcol]=size(Padding1);
            
            for i=2:row-1
                for j=2:col-1
                    s=0;
                    temp= ImageFile(i-1:i+1, j-1:j+1);
                    product= temp.*StructElement;
                    s=sum(product,"all");
                    if (s==7)
                        imgErode(i,j)= 1;
                    end
                    
                end
            end
            
            for i=1:row
                for j=1:col
                        Padding1(i+1,j+1)=imgErode(i,j);
                end
            end
            
            for i=2:newrow-1
                for j=2:newcol-1
                    temp= Padding1(i-1:i+1, j-1:j+1);
                    product= temp.*StructElement;
                    imgOpening(i-1,j-1)= max(max(product));
                end
            end  
            imshow((imgOpening),'Parent' ,app.MorphologicalImage);
            
            for i=1:row
                for j=1:col
                        Padding2(i+1,j+1)=imgOpening(i,j);
                end
            end
            
            for i=2:newrow-1
                for j=2:newcol-1
                    temp= Padding2(i-1:i+1, j-1:j+1);
                    product= temp.*StructElement;
                    imgDilate(i-1,j-1)= max(max(product));
                end
            end 
            
            for i=2:row-1
                for j=2:col-1
                    s=0;
                    temp= imgDilate(i-1:i+1, j-1:j+1);
                    product= temp.*StructElement;
                    s=sum(product,"all");
                    if (s==7)
                        imgClosinggg(i,j)= 1;
                    end
                    
                end
            end 
            imshow((imgClosinggg),'Parent' ,app.MorphologicalImage);            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            global ImageFile;
            global imgClosingg;
            StructElement = ones(3,3);
            StructElement(3, 1) = 0;
            StructElement(1, 3) = 0; 
            [row,col]=size(ImageFile);  
            imgErode= zeros(row,col);
            imgOpening= zeros(row,col);
            imgDilate= zeros(row,col);
            imgClosingg= zeros(row,col);
            Padding1=zeros(row+2,col+2);
            Padding2=zeros(row+2,col+2);
            [newrow,newcol]=size(Padding1);
            
            for i=2:row-1
                for j=2:col-1
                    s=0;
                    temp= ImageFile(i-1:i+1, j-1:j+1);
                    product= temp.*StructElement;
                    s=sum(product,"all");
                    if (s==7)
                        imgErode(i,j)= 1;
                    end
                    
                end
            end
            
            for i=1:row
                for j=1:col
                        Padding1(i+1,j+1)=imgErode(i,j);
                end
            end
            
            for i=2:newrow-1
                for j=2:newcol-1
                    temp= Padding1(i-1:i+1, j-1:j+1);
                    product= temp.*StructElement;
                    imgOpening(i-1,j-1)= max(max(product));
                end
            end  
            imshow((imgOpening),'Parent' ,app.MorphologicalImage);
            
            for i=1:row
                for j=1:col
                        Padding2(i+1,j+1)=imgOpening(i,j);
                end
            end
            
            for i=2:newrow-1
                for j=2:newcol-1
                    temp= Padding2(i-1:i+1, j-1:j+1);
                    product= temp.*StructElement;
                    imgDilate(i-1,j-1)= max(max(product));
                end
            end 
            
            for i=2:row-1
                for j=2:col-1
                    s=0;
                    temp= imgDilate(i-1:i+1, j-1:j+1);
                    product= temp.*StructElement;
                    s=sum(product,"all");
                    if (s==7)
                        imgClosingg(i,j)= 1;
                    end
                    
                end
            end 
            imshow((imgClosingg),'Parent' ,app.MorphologicalImage);
        end

        % Callback function
        function Button_5Pushed(app, event)
            global imgClosingg; 
            global imgClosinggg;
            x=max(imgClosingg,imgClosinggg);
            imshow((x),'Parent' ,app.MorphologicalImage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageProcessing = uifigure('Visible', 'off');
            app.ImageProcessing.Name = 'Image Processing';
            app.TabGroup = uitabgroup(app.ImageProcessing);
            app.ImageViewerTab = uitab(app.TabGroup);
            app.ImageViewerTab.Title = 'Image Viewer';
            app.BrowseButton = uibutton(app.ImageViewerTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.PathEditField = uieditfield(app.ImageViewerTab, 'text');
            app.WidthLabel = uilabel(app.ImageViewerTab);
            app.WidthLabel.Text = 'Width';
            app.HeightLabel = uilabel(app.ImageViewerTab);
            app.HeightLabel.Text = 'Height';
            app.SizeinbitsLabel = uilabel(app.ImageViewerTab);
            app.SizeinbitsLabel.Text = 'Size in bits';
            app.BitDepthLabel = uilabel(app.ImageViewerTab);
            app.BitDepthLabel.Text = 'Bit Depth';
            app.ImageColorLabel = uilabel(app.ImageViewerTab);
            app.ImageColorLabel.Text = 'Image Color';
            app.WidthEditField = uieditfield(app.ImageViewerTab, 'text');
            app.HeightEditField = uieditfield(app.ImageViewerTab, 'text');
            app.SizeInBitsEditField = uieditfield(app.ImageViewerTab, 'text');
            app.BitDepthEditField = uieditfield(app.ImageViewerTab, 'text');
            app.ImageColorEditField = uieditfield(app.ImageViewerTab, 'text');
            app.ModalityLabel = uilabel(app.ImageViewerTab);
            app.ModalityLabel.Text = 'Modality';
            app.PatientNameLabel = uilabel(app.ImageViewerTab);
            app.PatientNameLabel.Text = 'PatientName';
            app.PatientAgeLabel = uilabel(app.ImageViewerTab);
            app.PatientAgeLabel.Text = 'PatientAge';
            app.BodyPartExaminedLabel = uilabel(app.ImageViewerTab);
            app.BodyPartExaminedLabel.Text = 'BodyPartExamined';
            app.ModalityEditField = uieditfield(app.ImageViewerTab, 'text');
            app.PatientNameEditField = uieditfield(app.ImageViewerTab, 'text');
            app.PatientAgeEditField = uieditfield(app.ImageViewerTab, 'text');
            app.BodyPartExaminedEditField = uieditfield(app.ImageViewerTab, 'text');
            app.ImageDisplay = uiaxes(app.ImageViewerTab);
            zlabel(app.ImageDisplay, 'Z')
            app.ImageDisplay.XTick = [];
            app.ImageDisplay.YTick = [];
            app.InterpolationTab = uitab(app.TabGroup);
            app.InterpolationTab.Title = 'Interpolation';
            app.InterpolateButton = uibutton(app.InterpolationTab, 'push');
            app.InterpolateButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolateButton.Text = 'Interpolate';
            app.ZoomingFactorLabel = uilabel(app.InterpolationTab);
            app.ZoomingFactorLabel.Visible = 'off';
            app.ZoomingFactorLabel.Text = 'Zooming Factor';
            app.ZoomingFactorEditField = uieditfield(app.InterpolationTab, 'numeric');
            app.ZoomingFactorEditField.Limits = [0 16];
            app.ZoomingFactorEditField.Visible = 'off';
            app.InterpolationTypeDropDown = uidropdown(app.InterpolationTab);
            app.InterpolationTypeDropDown.Items = {'Select Interpolation Method', 'Nearest-Neighbour Interpolation', 'Linear Interpolation'};
            app.InterpolationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @InterpolationTypeDropDownValueChanged, true);
            app.InterpolationTypeDropDown.Visible = 'off';
            app.InterpolationTypeDropDown.Value = 'Select Interpolation Method';
            app.InterpolationPanel = uipanel(app.InterpolationTab);
            app.InterpolatedImage = uiaxes(app.InterpolationPanel);
            zlabel(app.InterpolatedImage, 'Z')
            app.InterpolatedImage.XTick = [];
            app.InterpolatedImage.YTick = [];
            app.OriginalPanel = uipanel(app.InterpolationTab);
            app.OriginalImage = uiaxes(app.OriginalPanel);
            zlabel(app.OriginalImage, 'Z')
            app.OriginalImage.XTick = [];
            app.OriginalImage.YTick = [];
            app.OriginalImageinGrayscaleLabel = uilabel(app.InterpolationTab);
            app.OriginalImageinGrayscaleLabel.Text = 'Original Image in Grayscale';
            app.InterpolatedImageLabel = uilabel(app.InterpolationTab);
            app.InterpolatedImageLabel.Text = 'Interpolated Image';
            app.OriginalWidthLabel = uilabel(app.InterpolationTab);
            app.OriginalWidthLabel.Text = 'Original Width';
            app.OriginalHeightLabel = uilabel(app.InterpolationTab);
            app.OriginalHeightLabel.Text = 'Original Height';
            app.NewWidthLabel = uilabel(app.InterpolationTab);
            app.NewWidthLabel.Text = 'New Width';
            app.NewHeightLabel = uilabel(app.InterpolationTab);
            app.NewHeightLabel.Text = 'New Height';
            app.OriginalWidthEditField = uieditfield(app.InterpolationTab, 'text');
            app.OriginalHeightEditField = uieditfield(app.InterpolationTab, 'text');
            app.NewWidthEditField = uieditfield(app.InterpolationTab, 'text');
            app.NewHeightEditField = uieditfield(app.InterpolationTab, 'text');
            app.info = uilabel(app.InterpolationTab);
            app.info.Text = '*Interpolate Button transfers Photo from Image Viewer to Interpolation tab, once clicked while there is a valid image in image viewer tab, user will be able to choose zooming factor and Interpolation type.';
            app.AffineTransformationTab = uitab(app.TabGroup);
            app.AffineTransformationTab.Title = 'Affine Transformation';
            app.ConstructImageButton = uibutton(app.AffineTransformationTab, 'push');
            app.ConstructImageButton.ButtonPushedFcn = createCallbackFcn(app, @ConstructImageButtonPushed, true);
            app.ConstructImageButton.Text = 'Construct Image';
            app.AngleSlider = uislider(app.AffineTransformationTab);
            app.AngleSlider.Limits = [-180 180];
            app.AngleSlider.MajorTicks = [-180 -150 -120 -90 -60 -30 0 30 60 90 120 150 180];
            app.AngleSlider.MajorTickLabels = {'-180', '-150', '-120', '-90', '-60', '-30', '0', '30', '60', '90', '120', '150', '180'};
            app.AngleSlider.ValueChangedFcn = createCallbackFcn(app, @AngleSliderValueChanged, true);
            app.AngleSlider.MinorTicks = [-180 -175 -170 -165 -160 -155 -150 -145 -140 -135 -130 -125 -120 -115 -110 -105 -100 -95 -90 -85 -80 -75 -70 -65 -60 -55 -50 -45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150 155 160 165 170 175 180];
            app.AngleSliderLabel = uilabel(app.AffineTransformationTab);
            app.AngleSliderLabel.Text = 'Angle Slider';
            app.InterpolationTypeOfRotatedImageDropDown = uidropdown(app.AffineTransformationTab);
            app.InterpolationTypeOfRotatedImageDropDown.Items = {'Select Interpolation Method', 'Nearest-Neighbour Interpolation', 'Linear Interpolation'};
            app.InterpolationTypeOfRotatedImageDropDown.ValueChangedFcn = createCallbackFcn(app, @InterpolationTypeOfRotatedImageDropDownValueChanged, true);
            app.InterpolationTypeOfRotatedImageDropDown.Value = 'Select Interpolation Method';
            app.InterpolationTypeLabel = uilabel(app.AffineTransformationTab);
            app.InterpolationTypeLabel.Text = 'Interpolation Type';
            app.ShearingAngleLabel = uilabel(app.AffineTransformationTab);
            app.ShearingAngleLabel.Text = 'Shearing Angle';
            app.Shear45Button = uibutton(app.AffineTransformationTab, 'push');
            app.Shear45Button.ButtonPushedFcn = createCallbackFcn(app, @Shear45ButtonPushed, true);
            app.Shear45Button.Text = 'Shear 45';
            app.ShearNegative45Button = uibutton(app.AffineTransformationTab, 'push');
            app.ShearNegative45Button.ButtonPushedFcn = createCallbackFcn(app, @ShearNegative45ButtonPushed, true);
            app.ShearNegative45Button.Text = 'Shear -45';
            app.AngleLabel = uilabel(app.AffineTransformationTab);
            app.AngleLabel.Text = 'Angle';
            app.DirectionLabel = uilabel(app.AffineTransformationTab);
            app.DirectionLabel.Text = 'Direction';
            app.AngleEditField = uieditfield(app.AffineTransformationTab, 'text');
            app.DirectionEditField = uieditfield(app.AffineTransformationTab, 'text');
            app.RotateWidthLabel = uilabel(app.AffineTransformationTab);
            app.RotateWidthLabel.Text = 'Width';
            app.RotateHeightLabel = uilabel(app.AffineTransformationTab);
            app.RotateHeightLabel.Text = 'Height';
            app.RotateWidthEditField = uieditfield(app.AffineTransformationTab, 'text');
            app.RotateHeightEditField = uieditfield(app.AffineTransformationTab, 'text');
            app.TImage = uiaxes(app.AffineTransformationTab);
            zlabel(app.TImage, 'Z')
            app.TImage.YDir = 'reverse';
            app.TImage.XAxisLocation = 'top';
            app.TImage.XTick = [];
            app.TImage.YTick = [];
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.HistogramBrowseButton = uibutton(app.HistogramTab, 'push');
            app.HistogramBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramBrowseButtonPushed, true);
            app.HistogramBrowseButton.Text = 'Browse';
            app.EqualizedImageLabel = uilabel(app.HistogramTab);
            app.EqualizedImageLabel.Text = 'Equalized Image';
            app.OriginalImageLabel = uilabel(app.HistogramTab);
            app.OriginalImageLabel.Text = 'Original Image';
            app.UnequalizedDisplay = uiaxes(app.HistogramTab);
            zlabel(app.UnequalizedDisplay, 'Z')
            app.UnequalizedDisplay.XTick = [];
            app.UnequalizedDisplay.YTick = [];
            app.EqualizedDisplay = uiaxes(app.HistogramTab);
            zlabel(app.EqualizedDisplay, 'Z')
            app.EqualizedDisplay.XTick = [];
            app.EqualizedDisplay.YTick = [];
            app.UnequalizedHistogram = uiaxes(app.HistogramTab);
            zlabel(app.UnequalizedHistogram, 'Z')
            app.EqualizedHistogram = uiaxes(app.HistogramTab);
            zlabel(app.EqualizedHistogram, 'Z')
            app.SpatialFilteringTab = uitab(app.TabGroup);
            app.SpatialFilteringTab.Title = 'Spatial Filtering';
            app.SpatialFilterBrowseButton = uibutton(app.SpatialFilteringTab, 'push');
            app.SpatialFilterBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @SpatialFilterBrowseButtonPushed, true);
            app.SpatialFilterBrowseButton.Text = 'Browse';
            app.BlurredNoisyImageLabel = uilabel(app.SpatialFilteringTab);
            app.BlurredNoisyImageLabel.Text = '';
            app.KernelsizeLabel = uilabel(app.SpatialFilteringTab);
            app.KernelsizeLabel.Text = 'Kernel size';
            app.FactorKLabel = uilabel(app.SpatialFilteringTab);
            app.FactorKLabel.Text = 'Factor K';
            app.KernelSizeSpinner = uispinner(app.SpatialFilteringTab);
            app.KernelSizeSpinner.Step = 2;
            app.KernelSizeSpinner.Limits = [1 99];
            app.KernelSizeSpinner.Value = 1;
            app.EnhanceButton = uibutton(app.SpatialFilteringTab, 'push');
            app.EnhanceButton.ButtonPushedFcn = createCallbackFcn(app, @EnhanceButtonPushed, true);
            app.EnhanceButton.Visible = 'off';
            app.EnhanceButton.Text = 'Enhance';
            app.RemoveNoiseButton = uibutton(app.SpatialFilteringTab, 'push');
            app.RemoveNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNoiseButtonPushed, true);
            app.RemoveNoiseButton.Visible = 'off';
            app.RemoveNoiseButton.Text = 'Remove Noise';
            app.EnhancedImageLabel = uilabel(app.SpatialFilteringTab);
            app.EnhancedImageLabel.Text = '';
            app.FactorKEditField = uieditfield(app.SpatialFilteringTab, 'numeric');
            app.FactorKEditField.RoundFractionalValues = 'on';
            app.UnfilteredImageDisplay = uiaxes(app.SpatialFilteringTab);
            zlabel(app.UnfilteredImageDisplay, 'Z')
            app.UnfilteredImageDisplay.XTick = [];
            app.UnfilteredImageDisplay.YTick = [];
            app.Blurred_NoisyDisplay = uiaxes(app.SpatialFilteringTab);
            zlabel(app.Blurred_NoisyDisplay, 'Z')
            app.Blurred_NoisyDisplay.XTick = [];
            app.Blurred_NoisyDisplay.YTick = [];
            app.EnhancedImageDisplay = uiaxes(app.SpatialFilteringTab);
            zlabel(app.EnhancedImageDisplay, 'Z')
            app.EnhancedImageDisplay.XTick = [];
            app.EnhancedImageDisplay.YTick = [];
            app.FourierITab = uitab(app.TabGroup);
            app.FourierITab.Title = 'Fourier I';
            app.FourierBrowseButton = uibutton(app.FourierITab, 'push');
            app.FourierBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @FourierBrowseButtonPushed, true);
            app.FourierBrowseButton.Text = 'Browse';
            app.LogDropDown = uidropdown(app.FourierITab);
            app.LogDropDown.Items = {'With Log', 'Without Log'};
            app.LogDropDown.ValueChangedFcn = createCallbackFcn(app, @LogDropDownValueChanged, true);
            app.LogDropDown.Value = 'With Log';
            app.MagnitudeLabel = uilabel(app.FourierITab);
            app.MagnitudeLabel.Text = 'Magnitude';
            app.PhaseLabel = uilabel(app.FourierITab);
            app.PhaseLabel.Text = 'Phase';
            app.OriginalImageLabel_2 = uilabel(app.FourierITab);
            app.OriginalImageLabel_2.Text = 'Original Image';
            app.OriginalImageDisplay = uiaxes(app.FourierITab);
            zlabel(app.OriginalImageDisplay, 'Z')
            app.OriginalImageDisplay.XTick = [];
            app.OriginalImageDisplay.YTick = [];
            app.MagnitudeDisplay = uiaxes(app.FourierITab);
            zlabel(app.MagnitudeDisplay, 'Z')
            app.MagnitudeDisplay.XTick = [];
            app.MagnitudeDisplay.YTick = [];
            app.PhaseDisplay = uiaxes(app.FourierITab);
            zlabel(app.PhaseDisplay, 'Z')
            app.PhaseDisplay.XTick = [];
            app.PhaseDisplay.YTick = [];
            app.FourierIITab = uitab(app.TabGroup);
            app.FourierIITab.Title = 'Fourier II';
            app.FourierIIBrowseButton = uibutton(app.FourierIITab, 'push');
            app.FourierIIBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @FourierIIBrowseButtonPushed, true);
            app.FourierIIBrowseButton.Text = 'Browse';
            app.FourierKernelsizeLabel = uilabel(app.FourierIITab);
            app.FourierKernelsizeLabel.Text = 'Kernel size';
            app.FourierKernelSizeSpinner = uispinner(app.FourierIITab);
            app.FourierKernelSizeSpinner.Step = 2;
            app.FourierKernelSizeSpinner.Limits = [1 99];
            app.FourierKernelSizeSpinner.Value = 1;
            app.FilterButton = uibutton(app.FourierIITab, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.FilteredImageLabel = uilabel(app.FourierIITab);
            app.FilteredImageLabel.Text = 'Filtered Image';
            app.DifferenceLabel = uilabel(app.FourierIITab);
            app.DifferenceLabel.Text = 'Difference';
            app.ImageDisplaay = uiaxes(app.FourierIITab);
            zlabel(app.ImageDisplaay, 'Z')
            app.ImageDisplaay.XTick = [];
            app.ImageDisplaay.YTick = [];
            app.FourierFilterDisplay = uiaxes(app.FourierIITab);
            zlabel(app.FourierFilterDisplay, 'Z')
            app.FourierFilterDisplay.XTick = [];
            app.FourierFilterDisplay.YTick = [];
            app.DifferenceDisplay = uiaxes(app.FourierIITab);
            zlabel(app.DifferenceDisplay, 'Z')
            app.DifferenceDisplay.XTick = [];
            app.DifferenceDisplay.YTick = [];
            app.RemovePatternTab = uitab(app.TabGroup);
            app.RemovePatternTab.Title = 'Remove Pattern';
            app.RemovePatternButton = uibutton(app.RemovePatternTab, 'push');
            app.RemovePatternButton.ButtonPushedFcn = createCallbackFcn(app, @RemovePatternButtonPushed, true);
            app.RemovePatternButton.Text = 'Remove Pattern';
            app.PatternedImageLabel = uilabel(app.RemovePatternTab);
            app.PatternedImageLabel.Text = 'Patterned Image';
            app.MaskingLabel = uilabel(app.RemovePatternTab);
            app.MaskingLabel.Text = 'Masking';
            app.PatternRemovedLabel = uilabel(app.RemovePatternTab);
            app.PatternRemovedLabel.Text = 'Pattern Removed';
            app.Mask = uiaxes(app.RemovePatternTab);
            zlabel(app.Mask, 'Z')
            app.Mask.XTick = [];
            app.Mask.YTick = [];
            app.ImageWithoutPattern = uiaxes(app.RemovePatternTab);
            zlabel(app.ImageWithoutPattern, 'Z')
            app.ImageWithoutPattern.XTick = [];
            app.ImageWithoutPattern.YTick = [];
            app.ImageWithPattern = uiaxes(app.RemovePatternTab);
            zlabel(app.ImageWithPattern, 'Z')
            app.ImageWithPattern.XTick = [];
            app.ImageWithPattern.YTick = [];
            app.NoiseTab = uitab(app.TabGroup);
            app.NoiseTab.Title = 'Noise';
            app.CreateImageButton = uibutton(app.NoiseTab, 'push');
            app.CreateImageButton.ButtonPushedFcn = createCallbackFcn(app, @CreateImageButtonPushed, true);
            app.CreateImageButton.Text = 'Create Image';
            app.NoiseTypeDropDown = uidropdown(app.NoiseTab);
            app.NoiseTypeDropDown.Items = {'No Noise', 'Gaussian Noise', 'Uniform Noise'};
            app.NoiseTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseTypeDropDownValueChanged, true);
            app.NoiseTypeDropDown.Value = 'No Noise';
            app.NoiseTypeLabel = uilabel(app.NoiseTab);
            app.NoiseTypeLabel.Text = 'Noise Type';
            app.ConfirmRegionofInterestButton = uibutton(app.NoiseTab, 'push');
            app.ConfirmRegionofInterestButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmRegionofInterestButtonPushed, true);
            app.ConfirmRegionofInterestButton.Text = 'Confirm Region of Interest';
            app.MeanLabel = uilabel(app.NoiseTab);
            app.MeanLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.NoiseTab, 'text');
            app.VarianceLabel = uilabel(app.NoiseTab);
            app.VarianceLabel.Text = 'Variance';
            app.VarianceEditField = uieditfield(app.NoiseTab, 'text');
            app.CreatedImage = uiaxes(app.NoiseTab);
            zlabel(app.CreatedImage, 'Z')
            app.CreatedImage.YDir = 'reverse';
            app.CreatedImage.XAxisLocation = 'top';
            app.CreatedImage.XTick = [];
            app.CreatedImage.YTick = [];
            app.ROIHistogram = uiaxes(app.NoiseTab);
            zlabel(app.ROIHistogram, 'Z')
            app.BackProjectionTab = uitab(app.TabGroup);
            app.BackProjectionTab.Title = 'Back Projection';
            app.StartButton = uibutton(app.BackProjectionTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ScheppLoganPhantomLabel = uilabel(app.BackProjectionTab);
            app.ScheppLoganPhantomLabel.Text = 'Schepp-Logan Phantom';
            app.SinogramLabel = uilabel(app.BackProjectionTab);
            app.SinogramLabel.Text = 'Sinogram';
            app.LaminogramLabel = uilabel(app.BackProjectionTab);
            app.LaminogramLabel.Text = 'Laminogram';
            app.LaminogramDropDown = uidropdown(app.BackProjectionTab);
            app.LaminogramDropDown.Items = {'Unfiltered Laminogram (0,20,40,60,160)', 'UnFiltered Laminogram (0:179)', 'RamLak', 'Hamming'};
            app.LaminogramDropDown.ValueChangedFcn = createCallbackFcn(app, @LaminogramDropDownValueChanged, true);
            app.LaminogramDropDown.Value = 'Unfiltered Laminogram (0,20,40,60,160)';
            app.ManualButton = uibutton(app.BackProjectionTab, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.BuiltinButton = uibutton(app.BackProjectionTab, 'push');
            app.BuiltinButton.ButtonPushedFcn = createCallbackFcn(app, @BuiltinButtonPushed, true);
            app.BuiltinButton.Text = 'Built-in';
            app.ScheppLoganPhantom = uiaxes(app.BackProjectionTab);
            zlabel(app.ScheppLoganPhantom, 'Z')
            app.ScheppLoganPhantom.XTick = [];
            app.ScheppLoganPhantom.YTick = [];
            app.Sinogram = uiaxes(app.BackProjectionTab);
            zlabel(app.Sinogram, 'Z')
            app.Sinogram.XTick = [];
            app.Sinogram.YTick = [];
            app.Laminogram = uiaxes(app.BackProjectionTab);
            zlabel(app.Laminogram, 'Z')
            app.Laminogram.XTick = [];
            app.Laminogram.YTick = [];
            app.ImageMorphologyTab = uitab(app.TabGroup);
            app.ImageMorphologyTab.Title = 'Image Morphology';
            app.DisplayButton = uibutton(app.ImageMorphologyTab, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.FingerprintImageLabel = uilabel(app.ImageMorphologyTab);
            app.FingerprintImageLabel.Text = 'Fingerprint Image';
            app.MorphologicalProcessingLabel = uilabel(app.ImageMorphologyTab);
            app.MorphologicalProcessingLabel.Text = 'Morphological Processing';
            app.DilationButton = uibutton(app.ImageMorphologyTab, 'push');
            app.DilationButton.ButtonPushedFcn = createCallbackFcn(app, @DilationButtonPushed, true);
            app.DilationButton.Text = 'Dilation';
            app.ErosionButton = uibutton(app.ImageMorphologyTab, 'push');
            app.ErosionButton.ButtonPushedFcn = createCallbackFcn(app, @ErosionButtonPushed, true);
            app.ErosionButton.Text = 'Erosion';
            app.ClosingButton = uibutton(app.ImageMorphologyTab, 'push');
            app.ClosingButton.ButtonPushedFcn = createCallbackFcn(app, @ClosingButtonPushed, true);
            app.ClosingButton.Text = 'Closing';
            app.OpeningButton = uibutton(app.ImageMorphologyTab, 'push');
            app.OpeningButton.ButtonPushedFcn = createCallbackFcn(app, @OpeningButtonPushed, true);
            app.OpeningButton.Text = 'Opening';
            app.Button = uibutton(app.ImageMorphologyTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.ImageMorphologyTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.ImageMorphologyTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.ImageMorphologyTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.RotateWidthLabel_2 = uilabel(app.ImageMorphologyTab);
            app.RotateWidthLabel_2.Text = 'Operations';
            app.RotateHeightLabel_2 = uilabel(app.ImageMorphologyTab);
            app.RotateHeightLabel_2.Text = 'Noise Removal';
            app.FingerprintImage = uiaxes(app.ImageMorphologyTab);
            zlabel(app.FingerprintImage, 'Z')
            app.FingerprintImage.XTick = [];
            app.FingerprintImage.YTick = [];
            app.MorphologicalImage = uiaxes(app.ImageMorphologyTab);
            zlabel(app.MorphologicalImage, 'Z')
            app.MorphologicalImage.XTick = [];
            app.MorphologicalImage.YTick = [];
            % Show the figure after all components are created
            app.ImageProcessing.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TaskNine

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageProcessing)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageProcessing)
        end
    end
end